classdef Panel_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CinemticaDirectaTab            matlab.ui.container.Tab
        CoordenadasarticularesPanel_2  matlab.ui.container.Panel
        Lamp_D                         matlab.ui.control.Lamp
        Q1Label_3                      matlab.ui.control.Label
        Q1d                            matlab.ui.control.Spinner
        Q2Label_3                      matlab.ui.control.Label
        Q2d                            matlab.ui.control.Spinner
        Q3Label_3                      matlab.ui.control.Label
        Q3d                            matlab.ui.control.Spinner
        CoordenadascartesianasPanel_2  matlab.ui.container.Panel
        YLabel_2                       matlab.ui.control.Label
        Yd                             matlab.ui.control.NumericEditField
        XLabel_2                       matlab.ui.control.Label
        Xd                             matlab.ui.control.NumericEditField
        ZLabel_2                       matlab.ui.control.Label
        Zd                             matlab.ui.control.NumericEditField
        CalcularD                      matlab.ui.control.Button
        MoverRobot                     matlab.ui.control.Button
        CinemticaInversaTab            matlab.ui.container.Tab
        CoordenadasarticularesPanel    matlab.ui.container.Panel
        Q2Label_4                      matlab.ui.control.Label
        Q2i                            matlab.ui.control.NumericEditField
        Q3Label                        matlab.ui.control.Label
        Q3i                            matlab.ui.control.NumericEditField
        Q1Label_4                      matlab.ui.control.Label
        Q1i                            matlab.ui.control.NumericEditField
        CoordenadascartesianasPanel    matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        XLabel                         matlab.ui.control.Label
        Xi                             matlab.ui.control.Spinner
        YLabel                         matlab.ui.control.Label
        Yi                             matlab.ui.control.Spinner
        ZLabel                         matlab.ui.control.Label
        Zi                             matlab.ui.control.Spinner
        CalcularI                      matlab.ui.control.Button
        MoverRobot2                    matlab.ui.control.Button
        ComunicacinTab                 matlab.ui.container.Tab
        ParmetrosPanel                 matlab.ui.container.Panel
        PuertoDropDownLabel            matlab.ui.control.Label
        Puerto                         matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        Baudrate                       matlab.ui.control.DropDown
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        SimulacinTab                   matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        BotonGrafico                   matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.Puerto.Items=seriallist;
         app.Baudrate.Items={'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200'};
         app.Baudrate.Value = {'115200'};
        end

        % Button pushed function: CalcularI
        function CalcularIPushed(app, event)
            x=[app.Xi.Value app.Yi.Value app.Zi.Value];
            if constrains(x)==1
               app.Lamp.Color='Green';
               [app.Q1i.Value,app.Q2i.Value,app.Q3i.Value] = inversa(app.Xi.Value, app.Yi.Value, app.Zi.Value);
            else  
               app.Lamp.Color='Red';
            end
        end

        % Button pushed function: CalcularD
        function CalcularDButtonPushed(app, event)
            Coord=[0 0 0];
            [Coord(1), Coord(2), Coord(3)]=directa(app.Q1d.Value,app.Q2d.Value,app.Q3d.Value);
            if constrains(Coord)==1
               app.Lamp_D.Color='Green';
               app.Xd.Value=Coord(1);
               app.Yd.Value=Coord(2);
               app.Zd.Value=Coord(3);
            else  
               app.Lamp_D.Color='Red';
            end
        end

        % Button pushed function: MoverRobot
        function MoverRobotPushed(app, event)
            COM=app.Puerto.Value;
            app.EditField.Value=COM;
        end

        % Button pushed function: BotonGrafico
        function BotonGraficoButtonPushed(app, event)
            rotate3d(app.UIAxes,'on');
            [x2,y2,z2,x1,y1,z1] = directa(app.Q1d.Value,app.Q2d.Value,app.Q3d.Value);
            plot3(app.UIAxes,[x1 x1 x2] ,[0 y1 y2] ,[0 z1 z2],['r']);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CinemticaDirectaTab = uitab(app.TabGroup);
            app.CinemticaDirectaTab.Title = 'Cinemática Directa';
            app.CoordenadasarticularesPanel_2 = uipanel(app.CinemticaDirectaTab);
            app.CoordenadasarticularesPanel_2.Title = 'Coordenadas articulares';
            app.Lamp_D = uilamp(app.CoordenadasarticularesPanel_2);
            app.Q1Label_3 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q1Label_3.Text = 'Q1';
            app.Q1d = uispinner(app.CoordenadasarticularesPanel_2);
            app.Q2Label_3 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q2Label_3.Text = 'Q2';
            app.Q2d = uispinner(app.CoordenadasarticularesPanel_2);
            app.Q2d.Value = 1.5;
            app.Q3Label_3 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q3Label_3.Text = 'Q3';
            app.Q3d = uispinner(app.CoordenadasarticularesPanel_2);
            app.Q3d.Value = -0.29;
            app.CoordenadascartesianasPanel_2 = uipanel(app.CinemticaDirectaTab);
            app.CoordenadascartesianasPanel_2.Title = 'Coordenadas cartesianas';
            app.YLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.YLabel_2.Text = 'Y';
            app.Yd = uieditfield(app.CoordenadascartesianasPanel_2, 'numeric');
            app.Yd.Editable = 'off';
            app.XLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.XLabel_2.Text = 'X';
            app.Xd = uieditfield(app.CoordenadascartesianasPanel_2, 'numeric');
            app.Xd.Editable = 'off';
            app.ZLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.ZLabel_2.Text = 'Z';
            app.Zd = uieditfield(app.CoordenadascartesianasPanel_2, 'numeric');
            app.Zd.Editable = 'off';
            app.CalcularD = uibutton(app.CinemticaDirectaTab, 'push');
            app.CalcularD.ButtonPushedFcn = createCallbackFcn(app, @CalcularDButtonPushed, true);
            app.CalcularD.Text = 'Calcular';
            app.MoverRobot = uibutton(app.CinemticaDirectaTab, 'push');
            app.MoverRobot.ButtonPushedFcn = createCallbackFcn(app, @MoverRobotPushed, true);
            app.MoverRobot.Text = 'Mover Robot';
            app.CinemticaInversaTab = uitab(app.TabGroup);
            app.CinemticaInversaTab.Title = 'Cinemática Inversa';
            app.CoordenadasarticularesPanel = uipanel(app.CinemticaInversaTab);
            app.CoordenadasarticularesPanel.Title = 'Coordenadas articulares';
            app.Q2Label_4 = uilabel(app.CoordenadasarticularesPanel);
            app.Q2Label_4.Text = 'Q2';
            app.Q2i = uieditfield(app.CoordenadasarticularesPanel, 'numeric');
            app.Q2i.Editable = 'off';
            app.Q3Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q3Label.Text = 'Q3';
            app.Q3i = uieditfield(app.CoordenadasarticularesPanel, 'numeric');
            app.Q3i.Editable = 'off';
            app.Q1Label_4 = uilabel(app.CoordenadasarticularesPanel);
            app.Q1Label_4.Text = 'Q1';
            app.Q1i = uieditfield(app.CoordenadasarticularesPanel, 'numeric');
            app.Q1i.Editable = 'off';
            app.CoordenadascartesianasPanel = uipanel(app.CinemticaInversaTab);
            app.CoordenadascartesianasPanel.Title = 'Coordenadas cartesianas';
            app.Lamp = uilamp(app.CoordenadascartesianasPanel);
            app.XLabel = uilabel(app.CoordenadascartesianasPanel);
            app.XLabel.Text = 'X';
            app.Xi = uispinner(app.CoordenadascartesianasPanel);
            app.YLabel = uilabel(app.CoordenadascartesianasPanel);
            app.YLabel.Text = 'Y';
            app.Yi = uispinner(app.CoordenadascartesianasPanel);
            app.ZLabel = uilabel(app.CoordenadascartesianasPanel);
            app.ZLabel.Text = 'Z';
            app.Zi = uispinner(app.CoordenadascartesianasPanel);
            app.CalcularI = uibutton(app.CinemticaInversaTab, 'push');
            app.CalcularI.ButtonPushedFcn = createCallbackFcn(app, @CalcularIPushed, true);
            app.CalcularI.Text = 'Calcular';
            app.MoverRobot2 = uibutton(app.CinemticaInversaTab, 'push');
            app.MoverRobot2.Text = 'Mover Robot';
            app.ComunicacinTab = uitab(app.TabGroup);
            app.ComunicacinTab.Title = 'Comunicación';
            app.ParmetrosPanel = uipanel(app.ComunicacinTab);
            app.ParmetrosPanel.Title = 'Parámetros';
            app.PuertoDropDownLabel = uilabel(app.ParmetrosPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.ParmetrosPanel);
            app.Puerto.Items = {};
            app.Puerto.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.ParmetrosPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.ParmetrosPanel);
            app.Baudrate.Items = {};
            app.Baudrate.Value = {};
            app.EditFieldLabel = uilabel(app.ComunicacinTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.ComunicacinTab, 'text');
            app.SimulacinTab = uitab(app.TabGroup);
            app.SimulacinTab.Title = 'Simulación';
            app.UIAxes = uiaxes(app.SimulacinTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-400 400];
            app.UIAxes.YLim = [-400 400];
            app.UIAxes.ZLim = [0 400];
            app.BotonGrafico = uibutton(app.SimulacinTab, 'push');
            app.BotonGrafico.ButtonPushedFcn = createCallbackFcn(app, @BotonGraficoButtonPushed, true);
            app.BotonGrafico.Text = 'Recalcular';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Panel_Control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end